classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        GridLayout        matlab.ui.container.GridLayout
        Panel             matlab.ui.container.Panel
        Btn_ExportData    matlab.ui.control.Button
        Btn_Reset         matlab.ui.control.Button
        Slider_Intensity  matlab.ui.control.Slider
        Label_2           matlab.ui.control.Label
        Drop_Algo         matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        Btn_Load          matlab.ui.control.Button
        UIAxes_Result     matlab.ui.control.UIAxes
        UIAxes_Original   matlab.ui.control.UIAxes
        Tab_2             matlab.ui.container.Tab
        GridLayout3       matlab.ui.container.GridLayout
        Btn_ROI           matlab.ui.control.Button
        UIAxes_SegMain    matlab.ui.control.UIAxes
        UIAxes_SegCrop    matlab.ui.control.UIAxes
        DTab              matlab.ui.container.Tab
        GridLayout2       matlab.ui.container.GridLayout
        Btn_3D            matlab.ui.control.Button
        UIAxes_3D         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    ImgOriginal % 记住原始图像数据
    ImgCurrent  % 记住当前处理后的图像数据
    % 新增的动态导出按钮属性
        Btn_Export        matlab.ui.control.Button
        Btn_Export3D      matlab.ui.control.Button
end

    methods (Access = private)
        
        % 这是一个专门用来实时处理裁剪图像的后台函数
        function processAndShowCrop(app, currentPosition)
            
            % 1. 根据传过来的实时坐标进行裁剪
            croppedImg = imcrop(app.ImgOriginal, currentPosition);
            
            % 2. 转成灰度图
            if size(croppedImg, 3) == 3
                grayCrop = rgb2gray(croppedImg);
            else
                grayCrop = croppedImg;
            end
            
            % 3. 提取边缘轮廓
            edgeCrop = edge(grayCrop, 'Canny');
            
            % 4. 实时显示在小画板上
            imshow(edgeCrop, 'Parent', app.UIAxes_SegCrop);
            title(app.UIAxes_SegCrop, 'ROI实时智能轮廓提取');
            
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Btn_Load
        function Btn_LoadButtonPushed(app, event)
           
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', '图像文件'});
            
            if isequal(file, 0)
                return;
            end
            
            fullFileName = fullfile(path, file);
            app.ImgOriginal = imread(fullFileName); 
            app.ImgCurrent = app.ImgOriginal;       
            
            imshow(app.ImgOriginal, 'Parent', app.UIAxes_Original);
            
            imshow(app.ImgCurrent, 'Parent', app.UIAxes_Result);
            
            uialert(app.UIFigure, '图像加载成功！请选择算法进行处理。', '系统提示', 'Icon', 'success');
        end

        % Value changed function: Drop_Algo
        function Drop_AlgoValueChanged(app, event)
            % 1. 防错机制：如果没有导入图片，直接弹窗警告并退出
            if isempty(app.ImgOriginal)
                uialert(app.UIFigure, '请先导入图像！', '操作错误', 'Icon', 'warning');
                return;
            end
            
            % 2. 获取用户当前选择的算法名称
            selectedAlgo = app.Drop_Algo.Value;
            
            % 3. 开启进度条（假装计算量很大，显得高级）
            d = uiprogressdlg(app.UIFigure, 'Title', '算法运行中', 'Message', ['正在执行: ', selectedAlgo]);
            
            try
                % 4. 根据选择执行不同的高级算法
                switch selectedAlgo
                    case '限制对比度增强'
                        % 增大 ClipLimit 的值（从0.02改成0.1或更大），让对比度撕裂感更强！
                        clipLimitValue = 0.1; 
                        
                        if size(app.ImgOriginal, 3) == 3
                            labImg = rgb2lab(app.ImgOriginal);
                            L = labImg(:,:,1) / 100;
                            % NumTiles调小，ClipLimit调大，效果肉眼可见！
                            L_enhanced = adapthisteq(L, 'NumTiles', [4 4], 'ClipLimit', clipLimitValue);
                            labImg(:,:,1) = L_enhanced * 100;
                            app.ImgCurrent = lab2rgb(labImg);
                        else
                            app.ImgCurrent = adapthisteq(app.ImgOriginal, 'NumTiles', [4 4], 'ClipLimit', clipLimitValue);
                        end
                        
                    case '伪彩色映射'
                        % 将图像转为灰度，再映射为Jet伪彩色（热成像效果）
                        if size(app.ImgOriginal, 3) == 3
                            grayImg = rgb2gray(app.ImgOriginal);
                        else
                            grayImg = app.ImgOriginal;
                        end
                        % 核心映射代码
                        app.ImgCurrent = ind2rgb(grayImg, jet(256));
                        
                    case '边缘提取'
                        % 使用 Canny 算子进行智能边缘检测
                        if size(app.ImgOriginal, 3) == 3
                            grayImg = rgb2gray(app.ImgOriginal);
                        else
                            grayImg = app.ImgOriginal;
                        end
                        edgeImg = edge(grayImg, 'Canny');
                        % 把逻辑矩阵转为可以显示的图像格式，并变成黑底白边
                        app.ImgCurrent = uint8(edgeImg) * 255; 
                end
                
                % 5. 将处理后的结果显示在右侧画板
                imshow(app.ImgCurrent, 'Parent', app.UIAxes_Result);
                
            catch ME
                % 如果算法出错，抓住错误并提示，程序不会崩溃
                uialert(app.UIFigure, ['处理失败: ' ME.message], '算法错误');
            end
            
            % 关闭进度条
            close(d);value = app.Drop_Algo.Value;
            
        end

        % Button pushed function: Btn_Reset
        function Btn_ResetButtonPushed(app, event)
            app.ImgCurrent = app.ImgOriginal; % 数据恢复
            imshow(app.ImgCurrent, 'Parent', app.UIAxes_Result); % 画面恢复
        end

        % Button pushed function: Btn_3D
        function Btn_3DButtonPushed(app, event)
            % 防错验证
            if isempty(app.ImgOriginal)
                uialert(app.UIFigure, '请先在第一页导入图像！', '提示');
                return;
            end
            
            % 开进度条
            d = uiprogressdlg(app.UIFigure, 'Title', '构建多维矩阵', 'Message', '正在进行3D渲染，请稍候...');
            
            % 1. 图像预处理：转为灰度图并缩小尺寸 (防止3D渲染卡死电脑)
            if size(app.ImgOriginal, 3) == 3
                grayImg = rgb2gray(app.ImgOriginal);
            else
                grayImg = app.ImgOriginal;
            end
            % 缩小到 200x200 像素，保证流畅度
            smallImg = imresize(grayImg, [200, 200]); 
            
            % 2. 核心！使用 surf 函数绘制 3D 表面图
            surf(app.UIAxes_3D, double(smallImg), 'EdgeColor', 'none');
            
            % 3. 增强3D视觉效果 (装逼参数设定)
            colormap(app.UIAxes_3D, 'parula'); % 使用高级渐变色
            view(app.UIAxes_3D, -37.5, 30);    % 设置最佳倾斜观察视角
            grid(app.UIAxes_3D, 'on');         % 打开网格
            
            % 4. 设置专业的图表标签
            title(app.UIAxes_3D, '图像空间亮度3D拓扑结构');
            xlabel(app.UIAxes_3D, 'X轴像素坐标');
            ylabel(app.UIAxes_3D, 'Y轴像素坐标');
            zlabel(app.UIAxes_3D, '像素强度 (灰度值)');
            
            % 5. 开启交互式操作！！！(让用户可以用鼠标转动3D图)
            enableDefaultInteractivity(app.UIAxes_3D);
            
            close(d);
        end

        % Button pushed function: Btn_ROI
        function Btn_ROIButtonPushed(app, event)
           % 1. 防错验证
            if isempty(app.ImgOriginal)
                uialert(app.UIFigure, '请先在第一页导入图像！', '操作错误');
                return;
            end
            
            % 2. 锁定画板！禁止图片被鼠标拖走（解决痛点1）
            disableDefaultInteractivity(app.UIAxes_SegMain);
            
            % 3. 显示原图
            imshow(app.ImgOriginal, 'Parent', app.UIAxes_SegMain);
            
            try
                % 4. 开启画框工具
                roi = drawrectangle(app.UIAxes_SegMain, 'Label', '目标提取区', 'Color', 'r', 'LineWidth', 2);
                
                % 5. 第一次画完后，立刻更新小图
                app.processAndShowCrop(roi.Position);
                
                % 6. 【神级代码：绑定实时事件监听器】（解决痛点2）
                % 当用户移动或调整红框大小(MovingROI)时，自动触发更新！
                addlistener(roi, 'MovingROI', @(src, event) app.processAndShowCrop(event.CurrentPosition));
                
            catch 
                disp('用户取消了框选操作');
            end
        end

        % Value changed function: Slider_Intensity
        function Slider_IntensityValueChanged(app, event)
            value = app.Slider_Intensity.Value;
            % 1. 防错：如果没有图，直接退出
            if isempty(app.ImgOriginal)
                return;
            end
            
            % 2. 获取滑块当前的值 (0 到 100)
            sliderVal = app.Slider_Intensity.Value;
            
            % 3. 获取当前下拉菜单选的是哪个算法
            selectedAlgo = app.Drop_Algo.Value;
            
            % 4. 根据不同的算法，让滑块发挥不同的魔力！
            switch selectedAlgo
                case '限制对比度增强'
                    % 【效果】滑块控制对比度的极限值
                    % 将 0-100 映射到 0.01 到 0.2 之间
                    clipLimitValue = 0.01 + (sliderVal / 100) * 0.19; 
                    
                    if size(app.ImgOriginal, 3) == 3
                        labImg = rgb2lab(app.ImgOriginal);
                        L = labImg(:,:,1) / 100;
                        L_enhanced = adapthisteq(L, 'NumTiles', [4 4], 'ClipLimit', clipLimitValue);
                        labImg(:,:,1) = L_enhanced * 100;
                        app.ImgCurrent = lab2rgb(labImg);
                    else
                        app.ImgCurrent = adapthisteq(app.ImgOriginal, 'NumTiles', [4 4], 'ClipLimit', clipLimitValue);
                    end
                    
                case '边缘提取'
                    % 【效果】滑块控制 Canny 算法找边缘的敏感度
                    % 敏感阈值大概在 0.1 到 0.8 之间
                    thresh = 0.1 + ((100 - sliderVal) / 100) * 0.7; 
                    
                    if size(app.ImgOriginal, 3) == 3
                        grayImg = rgb2gray(app.ImgOriginal);
                    else
                        grayImg = app.ImgOriginal;
                    end
                    % 动态改变阈值
                    edgeImg = edge(grayImg, 'Canny', thresh);
                    app.ImgCurrent = uint8(edgeImg) * 255;
                    
                case '伪彩色映射'
                    % 【效果】滑块控制伪彩色与灰度原图的“透明度混合(Alpha Blend)”
                    alpha = sliderVal / 100; % 将0-100转为 0到1的比例
                    
                    if size(app.ImgOriginal, 3) == 3
                        grayImg = rgb2gray(app.ImgOriginal);
                    else
                        grayImg = app.ImgOriginal;
                    end
                    
                    pseudoImg = ind2rgb(grayImg, jet(256));
                    % 将单通道灰度图转为三通道，以便与彩色图片混合
                    grayRGB = cat(3, grayImg, grayImg, grayImg) / 255;
                    
                    % 核心公式：图像融合矩阵计算
                    app.ImgCurrent = alpha * pseudoImg + (1 - alpha) * double(grayRGB);
            end
            
            % 5. 瞬间将画面更新到右侧结果框中！
            imshow(app.ImgCurrent, 'Parent', app.UIAxes_Result);
        end

        % Button pushed function: Btn_ExportData
        function Btn_ExportDataPushed(app, event)
            % 1. 防错：检查当前有没有经过处理的图像数据
            if isempty(app.ImgCurrent)
                uialert(app.UIFigure, '当前没有可导出的图像，请先导入并处理！', '操作错误', 'Icon', 'warning');
                return;
            end
            
            % 2. 召唤 MATLAB 标准的高级“保存文件”对话框
            % 支持 PNG, JPG, BMP 三种格式，默认名字为 Processed_Image.png
            [file, path] = uiputfile({ ...
                '*.png', '无损PNG 图像 (*.png)'; ...
                '*.jpg', '高压JPEG 图像 (*.jpg)'; ...
                '*.bmp', '位图 BMP (*.bmp)'}, ...
                '保存处理结果', 'Processed_Image.png');
            
            % 3. 防错：如果用户弹窗后反悔了，点了“取消”或关了窗口，直接退出防报错
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            
            % 4. 组合完整的保存路径
            fullFileName = fullfile(path, file);
            
            % 5. 核心：将内存中的矩阵数据真实写入本地磁盘
            try
                % 打开进度条装一下
                d = uiprogressdlg(app.UIFigure, 'Title', '导出中', 'Message', '正在写入磁盘...');
                
                imwrite(app.ImgCurrent, fullFileName);
                
                close(d);
                % 弹出成功提示
                uialert(app.UIFigure, ['图像已成功保存至：', newline, fullFileName], '导出成功', 'Icon', 'success');
                
            catch ME
                % 如果遇到C盘权限不足等问题，捕获错误不崩溃
                close(d);
                uialert(app.UIFigure, ['保存失败，请检查路径权限。', newline, '错误信息: ', ME.message], '导出失败', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '智能增强';
            app.GridLayout = uigridlayout(app.Tab);
            app.UIAxes_Original = uiaxes(app.GridLayout);
            title(app.UIAxes_Original, '原始图像')
            xlabel(app.UIAxes_Original, 'X')
            ylabel(app.UIAxes_Original, 'Y')
            zlabel(app.UIAxes_Original, 'Z')
            app.UIAxes_Result = uiaxes(app.GridLayout);
            title(app.UIAxes_Result, '处理结果')
            xlabel(app.UIAxes_Result, 'X')
            ylabel(app.UIAxes_Result, 'Y')
            zlabel(app.UIAxes_Result, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '控制台';
            app.Btn_Load = uibutton(app.Panel, 'push');
            app.Btn_Load.ButtonPushedFcn = createCallbackFcn(app, @Btn_LoadButtonPushed, true);
            app.Btn_Load.Text = '导入图像';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '选择算法';
            app.Drop_Algo = uidropdown(app.Panel);
            app.Drop_Algo.Items = {'限制对比度增强', '伪彩色映射', '边缘提取'};
            app.Drop_Algo.ValueChangedFcn = createCallbackFcn(app, @Drop_AlgoValueChanged, true);
            app.Drop_Algo.Value = '限制对比度增强';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '增强强度';
            app.Slider_Intensity = uislider(app.Panel);
            app.Slider_Intensity.ValueChangedFcn = createCallbackFcn(app, @Slider_IntensityValueChanged, true);
            app.Btn_Reset = uibutton(app.Panel, 'push');
            app.Btn_Reset.ButtonPushedFcn = createCallbackFcn(app, @Btn_ResetButtonPushed, true);
            app.Btn_Reset.Text = '恢复原图';
            app.Btn_ExportData = uibutton(app.Panel, 'push');
            app.Btn_ExportData.ButtonPushedFcn = createCallbackFcn(app, @Btn_ExportDataPushed, true);
            app.Btn_ExportData.Text = '导出结果';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '交互截图';
            app.GridLayout3 = uigridlayout(app.Tab_2);
            app.UIAxes_SegCrop = uiaxes(app.GridLayout3);
            title(app.UIAxes_SegCrop, '效果图')
            xlabel(app.UIAxes_SegCrop, 'X')
            ylabel(app.UIAxes_SegCrop, 'Y')
            zlabel(app.UIAxes_SegCrop, 'Z')
            app.UIAxes_SegMain = uiaxes(app.GridLayout3);
            title(app.UIAxes_SegMain, '原图')
            xlabel(app.UIAxes_SegMain, 'X')
            ylabel(app.UIAxes_SegMain, 'Y')
            zlabel(app.UIAxes_SegMain, 'Z')
            colormap(app.UIAxes_SegMain, 'winter')
            app.Btn_ROI = uibutton(app.GridLayout3, 'push');
            app.Btn_ROI.ButtonPushedFcn = createCallbackFcn(app, @Btn_ROIButtonPushed, true);
            app.Btn_ROI.Text = '交互截图';
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '3D映射地貌图';
            app.GridLayout2 = uigridlayout(app.DTab);
            app.UIAxes_3D = uiaxes(app.GridLayout2);
            title(app.UIAxes_3D, '3D地貌图')
            xlabel(app.UIAxes_3D, 'X')
            ylabel(app.UIAxes_3D, 'Y')
            zlabel(app.UIAxes_3D, 'Z')
            app.Btn_3D = uibutton(app.GridLayout2, 'push');
            app.Btn_3D.ButtonPushedFcn = createCallbackFcn(app, @Btn_3DButtonPushed, true);
            app.Btn_3D.Text = '生成3D地貌图';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end